% description: Model mosaics of arbitrary size of cones and rods that are interconnected via gap-junctions. Simulate the photoresponse in directly illuminated photoreceptors and model spread of signal
% across gap-junctions. Copyright 2024-2025 Luca Della Santina.
classdef ConeRodModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConeRodModelUIFigure  matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Panel                 matlab.ui.container.Panel
        Test                  matlab.ui.control.Button
        BatchUpdate           matlab.ui.control.Button
        btnInfo               matlab.ui.control.Button
        LoadButton            matlab.ui.control.Button
        BatchSteps            matlab.ui.control.NumericEditField
        StepsEditFieldLabel   matlab.ui.control.Label
        BatchTo               matlab.ui.control.NumericEditField
        ToEditFieldLabel      matlab.ui.control.Label
        BatchFrom             matlab.ui.control.NumericEditField
        FromEditFieldLabel    matlab.ui.control.Label
        BatchSetting          matlab.ui.control.DropDown
        BatchRun              matlab.ui.control.Button
        BatchList             matlab.ui.control.ListBox
        BatchAddRange         matlab.ui.control.Button
        BatchClear            matlab.ui.control.Button
        BatchRemove           matlab.ui.control.Button
        BatchAdd              matlab.ui.control.Button
        tblParams             matlab.ui.control.Table
        PlotButton            matlab.ui.control.Button
        SolveButton           matlab.ui.control.Button
        PnlPlots              matlab.ui.container.Panel
        ContextMenuPlots      matlab.ui.container.ContextMenu
        Menu3Dplot            matlab.ui.container.Menu
    end

%% ConeRodModel- Model a network of interconnected cones and rod
%  Copyright (C) 2024-2025 Luca Della Santina
%
%  This file is part of ConeRodModel
%
%  ObjectFinder is free software: you can redistribute it and/or modify
%  it under the terms of the GNU General Public License as published by
%  the Free Software Foundation, either version 3 of the License, or
%  (at your option) any later version.
%
%  This program is distributed in the hope that it will be useful,
%  but WITHOUT ANY WARRANTY; without even the implied warranty of
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%  GNU General Public License for more details.
%
%  You should have received a copy of the GNU General Public License
%  along with this program.  If not, see <https://www.gnu.org/licenses/>.
%    
    properties (Access = private)
        Version = '3.7'
        Params = struct % Constants and parameters of the model [IR,IC,CCjc,RRjc,RCjc,Rblocksjc,Rmc,Rmr,Cmc,Cmr,Er,Ec]
        Solution = struct % Solution of the model with current params
        Layout % Plotting area of the UI

        % Parameters of the rod cell (Liu & Kourennyi 2004)
        C_rod = 10; % Capacitance in pF
        ECa_rod = 40; % Calcium reversal potential in mV
        EKv_rod = -74; % Kv reversal potential in mV
        EKx_rod = -74; % Kx reversal potential in mV
        Eh_rod = -32; % Ih reversal potential in mV
        EKCa_rod = -74; % KCa reversal potential in mV
        ECl_rod = -20; % Cl reversal potential in mV
        EL_rod = -74; % Leak reversal potential in mV
        
        gCa_rod = 4; % Calcium conductance in nS
        gKv_rod = 10; % Kv conductance in nS
        gKx_rod = 1.04; % Kx conductance in nS
        gh_rod = 2.5; % Ih conductance in nS
        gKCa_rod = 5; % KCa conductance in nS
        gCl_rod = 1.3; % Cl conductance in nS
        gL_rod = 0.52; % Leak conductance in n
        
        K_KCa_rod = 0.00032; % KCa constant in mM
        K_Cl_rod = 0.0015; % Cl constant in mM
        Cainf_rod = 5e-5; % Steady-state calcium concentration in mM
        
        % Intracellular calcium concentration parameters
        volume_rod = (4/3) * pi * 7.8^3; % volume in um^3
        z = 2; % Valence of calcium ions
        tau_Cai_rod = 20; % Time constant for calcium decay in ms
        
        % Conversion factors
        fI = 10e-6; % Conversion factor for ICa
        fF = 10e9; % Conversion factor for Faraday's constant
        F = 96485; % Faraday constant (C/mol)
        
        % Rod photocurrent parameters
        Idark_rod = -42; % Dark current in pA

        % Gating variables (set by initalizeModel, updated by myode)
        nKv = []; % Kv activation
        hKv = []; % Kv inactivation (cones)
        nKx = []; % Kx inactivation (rods)
        nh = [];  % Ih activation
        mCa= [];  % Calcium activation
        hCa = []; % Calcium inactivation

        % Calcium concentration (set by initalizeModel, updated by myode)
        dCai_dt = [];
        Cai = []; % Initial intracellular calcium concentration in mM

        % Parameters of the cone cell (Kourennyi 2004)
        C_cone = 10; % Capacitance in pF
        ECa_cone = 40; % Calcium reversal potential in mV
        EKv_cone = -80; % Kv reversal potential in mV
        Eh_cone = -32.5; % Ih reversal potential in mV
        EKCa_cone = -80; % KCa reversal potential in mV
        ECl_cone = -45; % Cl reversal potential in
        EL_cone = -63; % Leak reversal potential in mV
        
        gCa_cone = 4.92; % Calcium conductance in nS
        gKv_cone = 2; % Kv conductance in nS
        gh_cone = 3.5; % Ih conductance in nS
        gKCa_cone = 0.5; % KCa conductance in nS
        gCl_cone = 6.5; % Cl conductance in nS 
        gL_cone = 1; % Leak conductance in nS
        
        aoCa = 3.1; % Calcium activation constant (1/s)
        SCa = 5.7; % Calcium scaling factor in mV
        VhalfCa = -16.6; % Calcium half activation voltage in mV
        Cainf_cone = 0.0001; % Steady-state calcium concentration in mM
        
        % Intracellular calcium concentration (Liu & Kourennyi 2004)
        volume_cone = (4/3) * pi * 7.8^3; % volume in um^3
        tau_Cai_cone = 20; % Time constant for calcium decay in ms
        
        % Cone photocurrent parameters
        Idark_cone = -20; % Dark current in pA 

        % Connectivity graph
        S_total;

        % Previous time of the Ode solver to keep track of dt
        t_prev=0;

        % Capacitance matrix
        C = [];
    end
    
    methods (Static)
        function P = getDefaultParams
            % P is a struct contains all initialized parameters

            P = struct;
            P.Tmax = 1;     % End Time of the simulation in second
            P.Tdelta = 0.001; % Sampling interval is seconds
            P.Tdelay = 0.5; % Time delay of flash trigger in seconds
            P.CCjc = 1e-11; % Direct cone/cone coupling conductance in S
            P.RRjc = 1e-11; % Direct rod/rod coupling conductance in S
            P.RCjc = 1e-10; % rod/cone coupling conductance in S
            P.RCjcRandom = false; % Whether to randomize RCjc or keep constant
            P.RCjcRndMean = 1.2e-9; % Mean of normal distribution to pick random values from
            P.RCjcRndSD = 0.4e-9; % Standard deviation of normal distribution to pick random values from
            P.Rblocksjc = 1e-11; % rod/rod coupling; connections between blocks of rods in S
            P.NoiseConeAmp = 0.5e-12; % cone noise amplitude in A;
            P.NoiseRodAmp = 0.5e-12; % rod noise amplitude in A;
            P.Rmc = 2.4e9;  % cone membrane resistance in ohms
            P.Rmr = 2.4e9;  % rod membrane resistance in ohms
            P.Cmc = 5e-12;  % cone membrane capacitance in F
            P.Cmr= 5e-12;   % rod membrane capacitance in F
            P.Er = -0.0428; % rod resting membrane potential in V
            P.Ec = -0.041;  % cone resting membrane potential in V 
            P.SPR = -1e-12; % Single photon response
            P.t1 = 0.010;   % 1st time-constant of intensity1
            P.t2 = 0.060;   % 2nd time-constant of intensity1
            P.RefCone = 5;  % Reference cone for plotting results
            P.ConeRows = 3; % Number of rows of Cones
            P.ConeCols = 3; % Number of columns of cones
            P.RodRows = 8;  % Number of rows of rods (per cone)
            P.RodCols = 5;  % Number of columns of rods (per cone)
            P.RodsIllNum = 1; % Number of rods illuminated (per cone)
            P.RodsIllRandom = false; % 50 percent of rods randomly illuminated (per cone)
            P.LRmodelFull = false; % Full light response model, True = Full Light Response, false = Single Photon Response
            P.FlashRstar = 1; % Light intensity of the flash in R*
            P.Illuminated = 1; % Determine how many rods are illuminated            
        end

        function D = getCurrentDateString
            % Returns the current date/time as a string, OS safe version
            % otherwise time includes ":" which is not allowred in
            % filennames on Windows OS

            D = char(datetime('now'));
            D = strrep(D, ':', '-');
        end
    end

    methods (Access = private)
        
        function dVdt = myode(app,t,V)            
            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones; % Total number of photoreceptors
            dt = t*1000-app.t_prev; % Retrieve current sampling interval in ms                        
            app.t_prev = t; % Update last timestamp processed
            mV = V.*1000; %convert V to mV (needed for model)

            % OLD: Current Vector, interpolated current at time t for all cells
            % TODO: Recalculate this using voltage-dependent conductances
            I = zeros(nPR,1);
            for pr = 1:nPR
                if pr<=nCones
                    if app.Params.LRmodelFull
                        % Full model with voltage-dependent conductances

                        % Update gating variables
                        amKv =  (5 * (mV(pr) - 100) / (1 - exp((100 - mV(pr)) / 42)));
                        bmKv =  (9 * exp((20 - mV(pr)) / 40));

                        ahKv =  (0.15 * exp(-mV(pr) / 22));
                        bhKv =  (0.4125 / (1 + exp((10 - mV(pr)) / 7)));

                        anh_cone =  (0.001 * 18 / (1 + exp((mV(pr) + 88) / 12)));
                        bnh_cone =  (0.001 * 18 / (1 + exp((-mV(pr) - 18) / 19)));

                        anCa =  (app.aoCa * exp((mV(pr) - app.VhalfCa) / (2 * app.SCa)));
                        bnCa =  (app.aoCa * exp((-mV(pr) + app.VhalfCa) / (2 * app.SCa)));

                        hKCa = app.Cai(pr) / (app.Cai(pr) + 0.3);
                        mCl =  1 / (1 + exp(0.00037 - app.Cai(pr)) / 0.00009);

                        % Update currents
                        ICa_cone = app.gCa_cone * app.mCa(pr) * (mV(pr) - app.ECa_cone); % Calcium current in pA
                        IKv_cone = app.gKv_cone * app.nKv(pr)^3 * app.hKv(pr) * (mV(pr) - app.EKv_cone); % Kv current in pA
                        IKCa_cone = app.gKCa_cone * hKCa(pr) * (mV(pr) - app.EKCa_cone); % KCa current in pA
                        ICl_cone = app.gCl_cone * mCl * (mV(pr) - app.ECl_cone); % Cl current in pA
                        IL_cone = app.gL_cone * (mV(pr) - app.EL_cone); % Leak current in pA

                        % Update Ih current
                        Ih_cone = app.gh_cone * app.nh(pr) * (mV(pr) - app.Eh_cone); % Ih current in pA

                        % Update channel gating variables
                        app.nKv(pr) = app.nKv(pr) + dt * (amKv * (1 - app.nKv(pr)) - bmKv * app.nKv(pr));
                        app.hKv(pr) = app.hKv(pr) + dt * (ahKv * (1 - app.hKv(pr)) - bhKv * app.hKv(pr));
                        app.nh(pr) = app.nh(pr) + dt * (anh_cone * (1 - app.nh(pr)) - bnh_cone * app.nh(pr));
                        app.mCa(pr) = app.mCa(pr) + dt * (anCa * (1 - app.mCa(pr)) - bnCa * app.mCa(pr));

                        % Update calcium concentration-
                        app.dCai_dt(pr) = -ICa_cone / (app.volume_cone * app.z * app.F * app.fI * app.fF) - (app.Cai(pr) - app.Cainf_cone) / app.tau_Cai_cone;
                        app.Cai(pr) = app.Cai(pr) + dt * app.dCai_dt(pr);

                        % Retrieve IphotoRod at time t from app.Params.IR(pr)
                        IphotoCone = interp1(app.Params.Itc,app.Params.IC(pr,:),t); % Cones

                        % Total Current (convert pA to A when necessary)
                        I(pr) = -IKv_cone*1e-12 ...
                                - Ih_cone*1e-12 ...
                                - ICa_cone*1e-12 ...
                                - IKCa_cone*1e-12 ...
                                - ICl_cone*1e-12 ...
                                - IL_cone*1e-12 ...
                                - app.Idark_cone*1e-12...
                                - IphotoCone;

                        I(pr) = I(pr)*1e-12; % Convert current from pA to A

                        % Uncomment this to debug individual currents
                        I(pr) = -IphotoCone;
                    else
                        I(pr) = interp1(app.Params.Itc,app.Params.IC(pr,:),t); % Cones
                    end
                else
                    if app.Params.LRmodelFull

                        % Update gating variables
                        anKv = 0.005 * (20 - mV(pr)) / (exp((20 - mV(pr)) / 22) - 1);
                        bnKv = 0.0625 * exp(-mV(pr) / 80);
    
                        anKx = 0.001 * 0.66 * exp((mV(pr) + 50) / (2 * 5.7));
                        bnKx = 0.001 * 0.66 * exp(-(mV(pr) + 50) / (2 * 5.7));
    
                        anh = 0.001 * exp((mV(pr) + 75) / (-10.6));
                        bnh = 0.001 * exp(-(mV(pr) + 75) / (-10.6));
    
                        amCa = 0.1 * exp((mV(pr) + 10) / 12);
                        bmCa = 0.1 * exp(-(mV(pr) + 10) / 12);
    
                        ahCa = 0.001 * 0.5 * exp(-(mV(pr) - 11) / 18);
                        bhCa = 0.001 * exp((mV(pr) - 11) / 18);
    
                        nKCa = 1 / (1 + (app.K_KCa_rod / app.Cai(pr))^4);
                        nCl = 1 / (1 + (app.K_Cl_rod / app.Cai(pr))^4);
    
                        % Update voltage-dependent currents
                        IKv_rod = app.gKv_rod * app.nKv(pr)^4 * (mV(pr) - app.EKv_rod); % IKv current in pA
                        IKx_rod = app.gKx_rod * app.nKx(pr) * (mV(pr) - app.EKx_rod); % IKx current in pA
                        ICa_rod = app.gCa_rod * app.mCa(pr) * app.hCa(pr) * (mV(pr) - app.ECa_rod); % Calcium current in pA
                        IKCa_rod = app.gKCa_rod * nKCa^4 * (mV(pr) - app.EKCa_rod); % IKCa current in pA
                        ICl_rod = app.gCl_rod * nCl * (mV(pr) - app.ECl_rod); % ICl current in pA
                        IL_rod = app.gL_rod * (mV(pr) - app.EL_rod); % IL current in pA

                        % Update Ih current
                        Ih_rod = app.gh_rod * app.nh(pr) * (mV(pr) - app.Eh_rod);
    
                        % Update channel gating variables
                        app.nKv(pr) = app.nKv(pr) + dt * (anKv * (1 - app.nKv(pr)) - bnKv * app.nKv(pr));
                        app.nKx(pr) = app.nKx(pr) + dt * (anKx * (1 - app.nKx(pr)) - bnKx * app.nKx(pr));
                        app.nh(pr) = app.nh(pr) + dt * (anh * (1 - app.nh(pr)) -  bnh * app.nh(pr));
                        app.mCa(pr) = app.mCa(pr) + dt * (amCa * (1 - app.mCa(pr)) - bmCa * app.mCa(pr));
                        app.hCa(pr) = app.hCa(pr) + dt * (ahCa * (1 - app.hCa(pr)) - bhCa * app.hCa(pr));

                        % Update Calcium concentration
                        app.dCai_dt(pr) = -ICa_rod / (app.volume_rod * app.z * app.F * app.fI * app.fF) - (app.Cai(pr) - app.Cainf_rod) / app.tau_Cai_rod;
                        app.Cai(pr) = app.Cai(pr) + dt * app.dCai_dt(pr);
    
                        % Retrieve IphotoRod at time t from app.Params.IR(pr)
                        IphotoRod = interp1(app.Params.Itr,app.Params.IR(pr,:),t);

                        % Total current
                        I(pr) = -IKv_rod*1e-12 ...
                                - IKx_rod*1e-12 ...
                                - Ih_rod*1e-12 ...
                                - ICa_rod*1e-12 ...
                                - IKCa_rod*1e-12 ...
                                - ICl_rod*1e-12 ...
                                - IL_rod*1e-12 ...
                                - app.Idark_rod*1e-12 ...
                                - IphotoRod;
                        
                        I(pr) = I(pr)*1e-12; % Convert current from pA to A

                        % Uncomment this to debug individual currents
                        I(pr) = -IphotoRod;
                    else
                        I(pr) = interp1(app.Params.Itr,app.Params.IR(pr,:),t); % Rods
                    end
                end
            end


            % Differential equation for voltage(t) = (S*V-I)/C
            dVdt=(app.C^-1)*(app.S_total*V-I); % Evaluate ODE at time t
            return
        end
        
        function S_total = Cone_graph(app)
            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;       % Total number of photoreceptors
            
            S_Rod=app.Rod_Graph;

            % Cone connectivity matrix (each cone connected to the one at its right and to the one below)
            S_Cone = zeros(nCones,nCones);
            for cone = 1:nCones-1
                % Connect cones horizontally left-to-right (except last of each row)
                if mod(cone, app.Params.ConeCols) > 0
                    S_Cone(cone,cone+1) = 1;
                end

               % Connect cones vertically top-to-bottom (except last of each column)
                if cone <= (nCones - app.Params.ConeCols)  
                    S_Cone(cone, cone+app.Params.ConeCols)  = 1;
                end
            end
            S_Cone(S_Cone==1) = app.Params.CCjc; % Assign a CCjc all connected cones in S_Cone:


            % Compose S_Cone and S_Rod into a single block diagonal matrix
            S_total=blkdiag(S_Cone,S_Rod);

            % Connect each cone to blocks of rods
            for cone = 1:nCones
                for rod = 1:nRPC
                    S_total(cone,nCones + nRPC*(cone-1) +rod)=app.Params.RCjc(rod);
                    S_total(nCones + nRPC*(cone-1) +rod, cone)=app.Params.RCjc(rod);
                end
            end

            % Making diagonal elements by summing all conductances in a row.
            for i=1:nPR
                for j=1:nPR
                    if i~=j
                        S_total(i,i)= S_total(i,j)+S_total(i,i);
                    end
                end
            end

            % Adding membrane conductance to diagonal elements
            for i=1:nCones  %for cones
                S_total(i,i)=-(S_total(i,i)+(1/app.Params.Rmc));
            end

            for i=nCones+1:nPR  %for rods
                S_total(i,i)=-(S_total(i,i)+(1/app.Params.Rmr));
            end
            
        end
        
        function S_Rod = Rod_Graph(app)
            % This function makes nCones blocks, each containing nRPC rods
            % S{cone} is the block of nRPC rods belonging to #cone
            % S{cone} is a nRPCxnRPC matrix encoding the condunctance of 
            %      Rod-Rod gap junctions
            %
            % S_Rod is a block diagonal matrix (nRPCxnCones,nRPCxnCones)
            %       where each element on the diagonal is S(cone}
            % 
            
            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            S_Rod = zeros(nRPC*nCones, nRPC*nCones, 'double');

            % Define gap-junction connectivity within blocks of rods
            % Each cone is associated with a block of nRPC rods (arranged RodRowsxRodCols)
            for cone = 1:nCones
                cone_idx = nRPC*(cone-1)+1;
                for rod = 1:nRPC-1
                    % Horizontal gap junction resistance from RRjc
                    if mod(rod,app.Params.RodCols) > 0
                        S_Rod(cone_idx+rod-1, cone_idx+rod) = app.Params.RRjc;
                    end

                    % Vertical gap junction resistance from RRjc
                    if rod <= (nRPC-app.Params.RodCols)
                        S_Rod(cone_idx+rod-1, cone_idx+rod+app.Params.RodCols-1) = app.Params.RRjc;
                    end
                end
            end

            % Define gap-junction connectivity between blocks of rods
            for cone = 1:nCones-1
                S_Rod_Pos = cone*nRPC;

                % connect horizontal matrix blocks from left to right (except last element of each row)
                if mod(cone,app.Params.ConeCols) > 0
                    for rod = 1:app.Params.RodRows
                        S_Rod((cone-1)*nRPC+rod*app.Params.RodCols, S_Rod_Pos+rod*app.Params.RodCols-app.Params.RodCols+1) = app.Params.Rblocksjc;
                    end
                end

                % connect vertical matrix blocks from top to bottom (except last element of each column)
                if cone <= (nCones - app.Params.ConeCols)
                    for rod = nRPC-app.Params.RodCols+1:nRPC
                        S_Rod((cone-1)*nRPC+rod, (cone+app.Params.ConeCols-1)*nRPC+rod-app.Params.RodCols) = app.Params.Rblocksjc;
                    end                    
                end
            end            
        end
        
        function P = initializeModel(app)
           
            P = app.getUserParams;          % Get user-defined parameters         
            nCones = P.ConeRows*P.ConeCols; % Total number of cones
            nRPC = P.RodRows*P.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;       % Total number of photoreceptors

            % IR, rod photocurrent (all rods treated as a group)
            % rods connected to cone 5 are elements #170-209 (or rods #160-199)
            % Itr is the vector containing all time steps

            P.Itr=linspace(0,P.Tmax,floor(P.Tmax/P.Tdelta)); % from t=0 to t=1s, 1000 points
            P.IR= zeros(nPR,floor(P.Tmax/P.Tdelta)); %reset to 0
            P.Illuminated= zeros(nPR,1); % 0=not illuminated, 1=illuminated

            % Determine which rod is illuminated (P.Illuminated = 1)
            for rod = nCones+1:nRPC:nPR
                IllumRPC = zeros(nRPC,1); % Default is no illumination, just random noise
                if P.RodsIllRandom
                    idxs = randperm(length(IllumRPC)); % Randomize indeces of illuminated rods
                    idxs = idxs(1:P.RodsIllNum);
                else
                    idxs = 1:P.RodsIllNum; % Pick the first RodsIllNum rods
                end
                IllumRPC(idxs) = 1;                    
                P.Illuminated(rod:rod+nRPC-1) = IllumRPC; % Store illumination status of each photoreceptor in P.Illuminated
            end

            % IR for all rods (either illuminated + noise or just noise)
            for rod = nCones+1:nPR
                P.IR(rod,:) = app.calcIrod(P, rod);
            end
            
            % IC for all cones, cone photocurrent
            % Itc is the vector containing all time steps
            P.Itc = linspace(0,P.Tmax,floor(P.Tmax/P.Tdelta)); % from t=0 to t=1s, 1000 points
            P.IC = zeros(nCones,floor(P.Tmax/P.Tdelta)); % reset to 0
            for cone = 1:nCones
                P.IC(cone,:) = app.calcIcone(P, cone);
            end

            RCjc = P.RCjc; % store user settings for constant RCjc            
            P.RCjc = zeros(nRPC); % RCjc rod/cone coupling conductance in S

            for i = 1:nRPC % choose from the following options:

                if P.RCjcRandom
                    % Random coupling for every rod (mean +/- sd)
                    a = P.RCjcRndSD; %standard deviation of normally distributed data
                    b = P.RCjcRndMean; %mean (S)
                    y = a.*randn(nRPC,1)+b;
                    P.RCjc(i,:)=(y);
                else
                    % Same coupling for all rods
                    for j = 1:nRPC               
                        P.RCjc(j) = RCjc;
                    end
                end
            end

            % Gating variables (updated by myode method)
            app.nKv = zeros(nPR, 1); % Kv activation
            app.hKv = zeros(nPR, 1); % Kv inactivation (cones)
            app.nKx = zeros(nPR, 1); % Kx inactivation (rods)
            app.nh = zeros(nPR, 1);  % Ih activation
            app.mCa= zeros(nPR, 1);  % Calcium activation
            app.hCa = zeros(nPR, 1); % Calcium inactivation
            app.dCai_dt = zeros(nPR, 1);
            app.Cai = zeros(nPR, 1); % Initial intracellular calcium concentration in mM

            % Initialize depending on photoreceptor type (cones vs rods)
            for pr = 1:nPR
                if pr<=nCones            
                    app.nKv(pr) = 0.3709; % Kv activation (cones)
                    app.hKv(pr) = 0.9998; % Kv inactivation (cones)
                    app.nKx(pr) = 0;
                    app.nh(pr) =  0.0877;  % Ih activation (cones)
                    app.mCa(pr) = 0.0115;  % Calcium activation (cones)
                    app.hCa(pr) = 0;
                    app.Cai(pr) = 0.00001; % Initial intracellular calcium concentration in mM (cones)
                else
                    app.nKv(pr) = 0.1518; % Kv activation (rods)
                    app.hKv(pr) = 0;
                    app.nKx(pr) = 0.7735; % Kx inactivation (rods)
                    app.nh(pr) = 0.0007;  % Ih activation (rods)
                    app.mCa(pr) = 0.0041;  % Calcium activation (rods)
                    app.hCa(pr) = 0.9975; % Calcium inactivation (rods)
                    app.Cai(pr) = 0.00001; % Initial intracellular calcium concentration in mM (rods)
                end
            end  

            app.t_prev = 0; % Reset last processed timestamp

            % Capacitance Matrix, storing the capacitance for each node
            app.C = zeros(nPR);
            for pr=1:nPR
                if pr<=nCones
                    app.C(pr,pr)=P.Cmc;  % Cone membrane capacitance;
                else
                    app.C(pr,pr)=P.Cmr; % Rod membrane capacitance;
                end
            end
            
        end
        
        function saveSolution(app, S)
            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;                         % Total number of photoreceptors

            % Saving the model output in Excel file
            filename = ['Model_' app.getCurrentDateString '_NodeVoltage.xlsx'];
            output = [S.t, S.V];
            rowsnum = nPR+1; % Number or rows in the output table
            V_Cell = cell(rowsnum,1);
            for i = 1:rowsnum
                if i==1
                    V_Cell{1,1}={'time'};
                elseif i>1 && i<=nCones+1
                    V_Cell{i,1}=sprintf('cone %d',i-1);
                elseif i>nCones+1 && i<=nPR+1
                    V_Cell{i,1}=sprintf('rod %d',i-1);
                end
            end
            T=table(V_Cell,output');
            writetable(T,filename)
        end
        
        function P = getUserParams(app)
            CE = table2cell(app.tblParams.Data);
            P = cell2struct(CE(:,2),CE(:,1),1);
        end
        
        function plotResults(app, pb)
            if isempty(pb)
                pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Plotting data...', 'Indeterminate','on');
            end

            if isfield(app.Params, 'RefCone')
                RefCone = app.Params.RefCone;
            else
                RefCone = 5;
            end

            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;                         % Total number of photoreceptors

            if RefCone > nCones
                msgbox("Selected reference cone does not exist","Error","error","modal");
                return
            end

            app.Layout = tiledlayout(app.PnlPlots, 5, 3, "TileSpacing","tight","Padding","tight");
            
            % Plot the network graph
            pb.Message = 'Connectivity graph';
            ax = nexttile(app.Layout, [2 3]);

            % elements #1-9 are cones, #10-369 are rods, rods that connects to cone #5
            % are elements #170-209 (rods #160-199)
            cm = triu(app.Cone_graph, 1); % Extract upper triangular part
            cm = cm + cm.'; % Add the transpose to make it symmetric
            G = graph(cm);
            p = plot(ax, G, 'Layout','force');

            % Set the z-coordinates for edges based on the connectivity matrix
            for i = 1:G.numnodes
                for j = i+1:G.numnodes
                    if cm(i, j) ~= 0
                        p.ZData([i j]) = [cm(i, j) cm(i, j)]; % Use edge lengths as z-coordinates
                    end
                end
            end            

            % Change color for markers based on cell identity
            Color_Cones = [1 0 0];
            Color_RefCone = [0.9290 0.6940 0.1250];
            Color_Rods = [0 0.1 0.5];
            Color_RodsIlluminated = [0 0.5 1];
            
            node_colors = zeros(sum(p.ZData>0), 3);
            node_colors(1:nCones, :) = repmat(Color_Cones, nCones, 1); % Set color of cones to red
            node_colors(RefCone, :) = Color_RefCone; % Set color of reference cone to orange
            node_colors(nCones+1:end, :) = repmat(Color_Rods, nPR-nCones, 1); % Set color of rods to light blue
            
            rods = find(app.Params.Illuminated);
            for rod = 1:numel(rods)
                node_colors(rods(rod), :) = Color_RodsIlluminated;
            end
            p.NodeColor = node_colors;
            
            p.MarkerSize = 8; % Set the marker size as desired
            p.EdgeColor = [0.5 0.5 0.5]; % Set the connection lines to grey
            if app.Menu3Dplot.Checked
                view(ax, 30, 40); % Adjust azimuth and elevation angles as needed
            end
            % Set a custom legend for the connectivity plot
            hold(ax,"on")
            h = zeros(4, 1);
            h(1) = plot(ax,NaN,NaN, '.', 'Color', Color_Cones,   'MarkerSize',24);
            h(2) = plot(ax,NaN,NaN, '.', 'Color', Color_RefCone, 'MarkerSize',24);
            h(3) = plot(ax,NaN,NaN, '.', 'Color', Color_Rods,    'MarkerSize',24);
            h(4) = plot(ax,NaN,NaN, '.', 'Color', Color_RodsIlluminated,    'MarkerSize',24);
            legend(h, {'Cone','Reference Cone','Rod', 'Rod Illuminated'}, 'Location', "northwest", "Box", "off");
            
            % rod photocurrent function IR0
            pb.Message = 'Photocurrents of all rods';            
            ax = nexttile(app.Layout);
            plot(ax, app.Params.Itr,app.Params.IR);     
            title(ax, 'Photocurrent of all rods')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Current (A)')
            ylim(ax, [-4e-12 4e-12])
            grid(ax, "on")

            % Plot voltage of all rods
            pb.Message = 'Membrane potential of all rods';            
            ax = nexttile(app.Layout);
            hold(ax,"on")
            for i=nCones+1:nPR
                plot(ax, app.Solution.t, app.Solution.V(:,i));
            end
            title(ax, 'Vm  of all rods')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Voltage (V)')
            grid(ax,"on")
            axis(ax, [0,app.Params.Tmax,-0.004+app.Params.Er,0.004+app.Params.Er])

            % Plot individual rods responses
            pb.Message = 'Response amplitude of all rods';            
            ax = nexttile(app.Layout);
            hold(ax,"on") 
            counter = 0;
            Vm = zeros(numel(nCones+1:nPR),3);
            for i = nCones+1:nPR
                counter = counter+1; 
                Vm(counter,1) = i;
                Vm(counter,2) = app.Solution.V(570,i);
                Vm(counter,3) = ismember(i, (RefCone-1)*nRPC+nCones+1 : RefCone*nRPC+nCones);           
                scatter(ax, 1, app.Solution.V(570,i),'jitter','on','jitterAmount',0.1);
            end
            ax.YDir = "reverse";
            title(ax, 'Response of all rods')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Response (V)')
            grid(ax, "on")
            axis(ax, [0.85,1.15,-0.004+app.Params.Er,0.004+app.Params.Er])
            text(ax,0.87,0.003, ['mean ± sd = ' num2str(mean(Vm(:,2)),3) ' ± ' num2str(std(Vm(:,2)),3)] );
            
            % Save values in a table the model output in Excel file
            filename = ['Model_' app.getCurrentDateString '_RodsResponseAmp.xlsx'];
            T = table;
            T.Rod = Vm(:,1);
            T.ResponseV = Vm(:,2);
            T.ConnectedToRefCone = Vm(:,3);
            writetable(T,filename)

            % cone photocurrent function
            pb.Message = 'Photocurrent of all cones';            
            ax = nexttile(app.Layout);
            plot(ax, app.Params.Itc, app.Params.IC);
            title(ax, 'Photocurrent of all cones')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Current (A)')
            ylim(ax, [-4e-12 4e-12])
            grid(ax,"on")

            % Plot membrane voltage of all cones
            pb.Message = 'Membrane voltage of all cones';            
            ax = nexttile(app.Layout);
            hold(ax,"on")
            for i=1:nCones
                plot(ax, app.Solution.t, app.Solution.V(:,i));
            end
            title(ax, 'Vm  of all cones')
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Voltage (V)')
            grid(ax, "on")
            axis(ax, [0,app.Params.Tmax,-0.004+app.Params.Ec,0.004+app.Params.Ec])

            % Plot membrane voltage of the rods illuminated
            pb.Message = 'Membrane voltage of the rods illuminated';            
            ax = nexttile(app.Layout);
            hold(ax,"on")
            for i = nCones+1:nPR
                if app.Params.Illuminated(i)
                    plot(ax, app.Solution.t, app.Solution.V(:,i));
                end
            end
            title(ax, ['Vm of illuminated rods (n=' num2str(sum(app.Params.Illuminated)) ')'])
            xlabel(ax, 'Time(s)')
            ylabel(ax, 'Voltage (V)')
            grid(ax, "on")
            axis(ax, [0,app.Params.Tmax,-0.004+app.Params.Er,0.004+app.Params.Er])

            % Plot Vm of reference cone
            pb.Message = 'Membrane potential of reference cone';            
            ax = nexttile(app.Layout);
            hold(ax,"on")
            plot(ax, app.Solution.t,app.Solution.V(:,RefCone));
            title(ax, ['Vm of reference cone #' num2str(RefCone)])
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Voltage (V)')
            grid(ax, "on")
            axis(ax, [0,app.Params.Tmax,-0.004+app.Params.Ec,0.004+app.Params.Ec])

            % Plot voltage of all rods coupled to reference cone
            pb.Message = 'Membrane potential of all rods connected to reference cone'; 
            ax = nexttile(app.Layout);
            hold(ax,"on")
            for i=(RefCone-1)*nRPC+nCones+1 : RefCone*nRPC+nCones
                plot(ax,app.Solution.t, app.Solution.V(:,i));
            end
            title(ax, ['Vm of rods coupled to reference cone #' num2str(RefCone)])
            xlabel(ax, 'Time(s)')
            ylabel(ax, 'Voltage (V)')
            grid(ax, "on")
            axis(ax, [0,app.Params.Tmax,-0.004+app.Params.Er,0.004+app.Params.Er])
            
            % Plot individual responses of rods connected to reference cone
            pb.Message = 'responses of rods connected to reference cone';
            ax = nexttile(app.Layout);
            hold(ax,"on")
            Vm = zeros(numel(nCones+1:nPR),2);
            counter = 0;
            for i=(RefCone-1)*nRPC+nCones+1 : RefCone*nRPC+nCones
                counter = counter+1; 
                Vm(counter,1) = i;
                Vm(counter,2) = app.Solution.V(570,i);                
                scatter(ax, 1, app.Solution.V(570,i),'jitter','on','jitterAmount',0.1);
            end
            ax.YDir = "reverse";
            title(ax, ['Response of rods coupled to reference cone #' num2str(RefCone)])
            xlabel(ax, 'Time (s)')
            ylabel(ax, 'Response amplitude (V)')
            grid(ax, "on")
            axis(ax, [0.85,1.15,-0.004+app.Params.Er,0.004+app.Params.Er])
            text(ax,0.87,0.003, ['mean ± sd = ' num2str(mean(Vm(:,2)),3) ' ± ' num2str(std(Vm(:,2)),3)] );

            
            pb.Message = 'Saving plots as PDF';
            exportgraphics(app.Layout, ['Model_' app.getCurrentDateString '_Plots.pdf']);

            pb.Message = 'Saving results spreadsheet';
            app.saveSolution(app.Solution);
        end
        
        
        function S = solveModel(app, pb)
            if isempty(pb)
                pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Plotting data...', 'Indeterminate','on');
            end            
            
            pb.Message = 'Initializing model...';
                        
            S = struct;
            S.Params = app.initializeModel;
            S.ParamsTable = app.tblParams.Data;
            app.Params = S.Params;

            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;       % Total number of photoreceptors
            
            if app.Params.RefCone > nCones
                msgbox("Selected reference cone does not exist","Error","error","modal");
                return
            end            
            
            % Calculate conductance matrix graph;
            app.S_total = app.Cone_graph;
            
            pb.Message = 'Solving differential equation, please wait...';

            % Initial parameters for the ODE solver
            % see myode() function for the differential equation to solve

            tspan = 0:S.Params.Tdelta:S.Params.Tmax; % explore from t=0 to t=Tmax in Tdelta intervals (seconds)            
            V0 = zeros(nPR,1); % All rods and cones populations
            %V0(1:nCones,1) = S.Params.Ec; % Resting potential for cones
            %V0(nCones+1:nPR,1) = S.Params.Er; % Resting potential for rods
            options = odeset('RelTol',1e-3, 'Maxstep', 1); % resolution, Maxstep to set at 1 for noise analysis

            %tic;
            %profile on;
            if app.Params.LRmodelFull
                % ode23s needed for the full model as ode15s cannot solve
                [S.t,S.V] = ode23s(@(t,V) app.myode(t,V), tspan, V0, options);
            else
                [S.t,S.V] = ode15s(@(t,V) app.myode(t,V), tspan, V0, options);
            end
            %disp(['Time elapsed:' num2str(toc)]);
            %profile off;

            pb.Message = 'Correct voltages by resting membrane potential';
            for i=1:nPR
                if i>=1 && i<=nCones
                    S.V(:,i) = S.V(:,i)+S.Params.Ec;
                else
                    S.V(:,i) = S.V(:,i)+S.Params.Er;
                end
            end
            
            pb.Message = 'Saving plots as PDF';
            save(['Model_' app.getCurrentDateString '.mat'], "S");            
        end
        
        function I = calcIrod(~, P, rod)
            % Calculates photocurrent for the specified rod
            % using either the Single-photon response or Full light
            % response model.
            % P = current parameters for the model
            % rod = current rod number for which current is computed

            if P.LRmodelFull
                % Full light reponse model

                % Hill equation for Iamp
                n=1; % Hill coefficient
                Iamp_max = 30; % Maximal amplitude of photocurrent in pA
                R_half = 4; % Flash intensity at half-maximal amplitude
                R_star = P.FlashRstar; % Flash intensity in R*
                R_star = max(R_star, 1e-6); % Prevent log(0)
                Iamp = Iamp_max * (R_star^n / (R_star^n + R_half^n));
                
                % Time constants and c based on R_star
                if R_star <= 100000
                    c = (366.05 * log(R_star / R_half) + 1347)/3;
                    tau1 = (-5.263 * log(R_star / R_half) + 55.235)/3;
                    tau2 = (20.392 * log(R_star / R_half) + 236.09)/3;
                end
                
                % Prepare constants needed for this code
                t_end = P.Tmax*1000;
                t_del = P.Tdelay*1000;
                dt = P.Tdelta*1000;
                num_time_steps = P.Tmax/P.Tdelta;
                time = linspace(0, t_end - dt, num_time_steps);  % Exclude the final step at t_end

                % Compute the original Iphoto
                I = Iamp .* ((1 - exp(-(time - t_del) / tau1))) .* (time > t_del) - ...
                    Iamp .* (1 ./ (1 + exp(-(time - t_del - c) / tau2))) .* (time > t_del);
            
                % Preallocate noise array
                I_noise_photo = zeros(1, num_time_steps);
            
                sigma_noise = 1;
                tau_noise = 1;
            
                % Generate noise for Iphoto
                for i = 1:num_time_steps - 1
                    noise = sigma_noise * sqrt(2 / tau_noise) * randn() / sqrt(dt);
                    I_noise_photo(i + 1) = I_noise_photo(i) + dt * (-I_noise_photo(i) / tau_noise + noise);
                end
            
                if P.Illuminated(rod)
                    I(I < 0) = 0; % Clamp photocurrent to ensure it does not go below zero
                    I = I + I_noise_photo; % Add Iphoto to noise
                else
                    I = I_noise_photo; % keep only noise if not illuminated
                end
                I = I.*1e-12; % Convert current from pA to A (default unit of measurement of the app)

            else
                % Single-photon response model
                noisy_r = (P.NoiseRodAmp)*randn(1,floor(P.Tmax/P.Tdelta)); %randn returns random value around 0, Gaussian distribution with sd=1
                myHeaviside = @(V)(1+sign(V))/2; % Frames single photon response at t=1s
                I = (noisy_r)-1.*P.Illuminated(rod).*(P.SPR.*(myHeaviside(P.Itr-P.Tdelay).*(1-exp(-(P.Itr-P.Tdelay)./P.t1))-myHeaviside(P.Itr-P.Tdelay-0.08).*(1-exp(-(P.Itr-P.Tdelay-0.08)./P.t2))));
            end
        end

        function I = calcIcone(~, P, cone)
            % Calculates photocurrent for the specified cone
            % using either the Single-photon response or Full light
            % response model.
            % P = current parameters for the model
            % cone = current cone number for which current is computed

            if P.LRmodelFull
                % Full light reponse model

                % Hill equation for Iamp
                n=1; % Hill coefficient
                Iamp_max = 30; % Maximal amplitude of photocurrent in pA
                R_half = 400; % Flash intensity at half-maximal amplitude
                R_star = P.FlashRstar; % Flash intensity in R*
                R_star = max(R_star, 1e-6); % Prevent log(0)
                Iamp = Iamp_max * (R_star^n / (R_star^n + R_half^n));
                
                % Time constants and c based on R_star
                if R_star < 40000
                    c = (5.05 * log(R_star / R_half) + 300)/2;
                    tau1 = (-5.263 * log(R_star / R_half) + 26.235)/2;
                    tau2 = (2.392 * log(R_star / R_half) + 236.09)/3;
                end
                
                % Prepare constants needed for this code
                t_end = P.Tmax*1000;
                t_del = P.Tdelay*1000;
                dt = P.Tdelta*1000;
                num_time_steps = P.Tmax/P.Tdelta;
                time = linspace(0, t_end - dt, num_time_steps);  % Exclude the final step at t_end

                % Compute the original Iphoto
                I = Iamp .* ((1 - exp(-(time - t_del) / tau1))) .* (time > t_del) - ...
                    Iamp .* (1 ./ (1 + exp(-(time - t_del - c) / tau2))) .* (time > t_del);
            
                % Preallocate noise array
                I_noise_photo = zeros(1, num_time_steps);
            
                sigma_noise = 1;
                tau_noise = 1;
            
                % Generate noise for Iphoto
                for i = 1:num_time_steps - 1
                    noise = sigma_noise * sqrt(2 / tau_noise) * randn() / sqrt(dt);
                    I_noise_photo(i + 1) = I_noise_photo(i) + dt * (-I_noise_photo(i) / tau_noise + noise);
                end            
                
                if P.Illuminated(cone)
                    I(I < 0) = 0; % Clamp photocurrent to ensure it does not go below zero
                    I = I + I_noise_photo; % Add Iphoto to noise
                else
                    I = I_noise_photo; % keep only noise if not illuminated
                end
                I = I.*1e-12; % Convert current from pA to A (default unit of measurement of the app)

            else
                % Single-photon response model
                noisy_c = (P.NoiseConeAmp)*randn(1,floor(P.Tmax/P.Tdelta)); % randn returns random value around 0, Gaussian distribution with sd=1
                I = (noisy_c)-1.*P.Illuminated(cone); % No single photon response in the cones, just noise      
            end
        end
        
        function S = testSoluton(app, pb)
            if isempty(pb)
                pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Plotting data...', 'Indeterminate','on');
            end            
            
            pb.Message = 'Initializing model...';
                        
            S = struct;
            S.Params = app.initializeModel;
            S.ParamsTable = app.tblParams.Data;
            app.Params = S.Params;

            nCones = app.Params.ConeRows*app.Params.ConeCols; % Total number of cones
            nRPC = app.Params.RodRows*app.Params.RodCols;     % Total number of rods per cone
            nPR = nRPC*nCones+nCones;       % Total number of photoreceptors
            
            if app.Params.RefCone > nCones
                msgbox("Selected reference cone does not exist","Error","error","modal");
                return
            end            
            
            % Calculate conductance matrix graph;
            app.S_total = app.Cone_graph;
            
            pb.Message = 'Solving differential equation, please wait...';

            % Initial parameters for the ODE solver
            % see myode() function for the differential equation to solve

            tspan = 0:S.Params.Tdelta:S.Params.Tmax; % explore from t=0 to t=Tmax in Tdelta intervals (seconds)            
            V0 = zeros(nPR,1); % All rods and cones populations
            %V0(1:nCones,1) = S.Params.Ec; % Resting potential for cones
            %V0(nCones+1:nPR,1) = S.Params.Er; % Resting potential for rods
            options = odeset('RelTol',1e-3, 'Maxstep', 1); % resolution, Maxstep to set at 1 for noise analysis

            % OLD code using the ode solver
            %tic;
            %profile on;
            % if app.Params.LRmodelFull
            %     % ode23s needed for the full model as ode15s cannot solve
            %     [S.t,S.V] = ode23s(@(t,V) app.myode(t,V), tspan, V0, options);
            % else
            %     [S.t,S.V] = ode15s(@(t,V) app.myode(t,V), tspan, V0, options);
            % end

            % New code: Calculate S.T and S.V by invoking myode in a for loop for
            % dt=1ms
            % Store result of myode(t,V) in S.t, S.V(t) 
            %disp(['Time elapsed:' num2str(toc)]);
            %profile off;

            % Plot Voltage over time for all photoreceptors
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the dialog on main window
            posParent = get(0,'ScreenSize');
            pos = app.ConeRodModelUIFigure.Position;
            pos(1) = ceil(posParent(1)+posParent(3)/2-pos(3)/2);
            pos(2) = ceil(posParent(2)+posParent(4)/2-pos(4)/2);
            app.ConeRodModelUIFigure.Position = pos;        

            app.ConeRodModelUIFigure.Name = [app.ConeRodModelUIFigure.Name ' ' app.Version];
            
            % Initialize table of parameters with default values
            P = app.getDefaultParams;
            t = table(fieldnames(P), struct2cell(P));
            app.tblParams.Data = t;
            app.BatchSetting.Items = t{:,1};
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            % Solve model with current settings
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Solving Model', 'Indeterminate','on');

            app.Solution = app.solveModel(pb);
            app.PlotButton.Enable = "on";
            close(pb);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Plotting data...', 'Indeterminate','on');
            app.plotResults(pb);           
            close(pb);
        end

        % Button pushed function: BatchRemove
        function BatchRemoveButtonPushed(app, event)
            idx = app.BatchList.ValueIndex;
            app.BatchList.Items(idx) = [];
            app.BatchList.ItemsData(idx) = [];
            if isempty(app.BatchList.Items)
                app.BatchRemove.Enable = "off";
                app.BatchClear.Enable = "off";
                app.BatchRun.Enable = "off";
                app.BatchUpdate.Enable = "off";
            end
        end

        % Button pushed function: BatchClear
        function BatchClearButtonPushed(app, event)
            switch uiconfirm(app.ConeRodModelUIFigure, 'Remove all items from list?','Delete items','Options', {'No', 'Yes'}, 'DefaultOption',1,'CancelOption',1)
                case 'Yes'
                    app.BatchList.Items = {};
                    app.BatchList.ItemsData = [];
                    app.BatchRemove.Enable = "off";
                    app.BatchClear.Enable = "off";
                    app.BatchRun.Enable = "off";
                    app.BatchUpdate.Enable = "off";
            end
        end

        % Button pushed function: BatchAdd
        function BatchAddButtonPushed(app, event)
            JobTitle = char(app.getCurrentDateString);
            L = app.tblParams.Data; % List of user-selected Settings
         
            if isempty(app.BatchList.Items)
                app.BatchList.Items = {JobTitle};
                app.BatchList.ItemsData = {L};
            else
                app.BatchList.Items{end+1} = JobTitle;
                app.BatchList.ItemsData(end+1) = {L};
            end
            app.BatchRemove.Enable = "on";
            app.BatchClear.Enable = "on";
            app.BatchRun.Enable = "on";
        end

        % Double-clicked callback: BatchList
        function BatchListDoubleClicked(app, event)
            item = app.BatchList.Value;
            if ~isempty(item)
                app.tblParams.Data = item;
            end
        end

        % Button pushed function: BatchAddRange
        function BatchAddRangeButtonPushed(app, event)
            if app.BatchFrom.Value >= app.BatchTo.Value
                msgbox("Invalid range, please check the typed values.","Error","error");
                return
            end

            JobTitle = char(app.getCurrentDateString);
            L = app.tblParams.Data; % List of user-selected Settings
            S = app.BatchSetting.Value;
            Sidx = find(strcmp(S, L{:,1}),1);

            % Create several jobs for the range of values explored
            for V = app.BatchFrom.Value:app.BatchSteps.Value:app.BatchTo.Value                
                JobTitleStep = [JobTitle ' ' S '=' num2str(V)];
                Lstep = L;
                Lstep{Sidx,2} = {V};
                                
                if isempty(app.BatchList.Items)
                    app.BatchList.Items = {JobTitleStep};
                    app.BatchList.ItemsData = {Lstep};
                else
                    app.BatchList.Items{end+1} = JobTitleStep;
                    app.BatchList.ItemsData(end+1) = {Lstep};
                end
                
            end

            app.BatchRemove.Enable = "on";
            app.BatchClear.Enable = "on";
            app.BatchRun.Enable = "on";            
        end

        % Button pushed function: BatchRun
        function BatchRunButtonPushed(app, event)
            % Solve model with current settings
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Batch processing:','Value', 0, 'Cancelable', 'on');

            for i = 1:numel(app.BatchList.Items)
                pb.Title = ['Batch processing: Model ' num2str(i) ' of ' num2str(numel(app.BatchList.Items))];
                pb.Value = i/numel(app.BatchList.Items);                
                app.BatchList.ValueIndex = i;
                app.tblParams.Data = app.BatchList.Value;
                app.Solution = app.solveModel(pb);
                app.plotResults(pb);

                if pb.CancelRequested
                    break;
                end                
            end

            close(pb);            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fName, PathName] = uigetfile({'*.mat','Supported formats (*.mat)'; '*.mat','Models (*.mat)'}, 'Select model', 'MultiSelect', 'off');
            
            app.ConeRodModelUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ConeRodModelUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            if fName == 0
                return
            end
            
            % Read data from saved simulation
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Importing preset...','Indeterminate', 'on');
            pb.Message = ['Loading model from ' fName];
            Model = load(fullfile(PathName, fName),"S");
            app.Solution = Model.S;
            app.Params = Model.S.Params;

            % Parameters introduces after v2.0, use default if missing        
            if ~isfield(app.Params, 'RefCone') % Reference cone number
                app.Params.RefCone = 5;
            end
            if ~isfield(app.Params, 'ConeRows') % Number of cones modeled
                app.Params.ConeRows = 3;
            end
            if ~isfield(app.Params, 'ConeCols') % Number of cones modeled
                app.Params.ConeCols = 3;
            end
            if ~isfield(app.Params, 'RodRows') % Number of cones modeled
                app.Params.RodRows = 8;
            end
            if ~isfield(app.Params, 'RodCols') % Number of cones modeled
                app.Params.RodCols = 5;
            end

            % Parameters introduced after v3.0, use default if missing
            if ~isfield(app.Params, 'NoiseConeAmp') % rod noise amplitude;
                app.Params.NoiseConeAmp = 0.5e-12; 
            end
            if ~isfield(app.Params, 'NoiseRodAmp') % rod noise amplitude;
                app.Params.NoiseRodAmp = 0.5e-12; 
            end
            if ~isfield(app.Params, 'Tmax') % End time of the simulation in s
                app.Params.Tmax = 1; 
            end            
            if ~isfield(app.Params, 'Tdelta') % Sampling time in s
                app.Params.Tdelta = 1; 
            end
            if ~isfield(app.Params, 'RCjcRndMean') % Number of cones modeled
                app.Params.RCjcRndMean = 1.2e-9; % Mean of normal distribution to pick random values from
            end
            if ~isfield(app.Params, 'RCjcRndSD') % Number of cones modeled
                app.Params.RCjcRndSD = 0.4e-9; % Standard deviation of normal distribution to pick random values from
            end
            if ~isfield(app.Params, 'RodsIllNum') % Number of cones modeled
                app.Params.RodsIllNum = 1; % Number of rods illuminated per cone
            end          
            if ~isfield(app.Params, 'RodsIllRandom') % Random chance of being illuminated
                app.Params.RodsIllRandom = false; % Random chance of being illuminated (50% chance per rod)
            end
            if ~isfield(app.Params, 'LRmodelFull') % Light Response Model (True = Full, False = SPR)
                app.Params.LRmodelFull = false; % Single Photon Response (default)
            end           
            if ~isfield(app.Params, 'FlashRstar') % Flash light intensity (in R*)
                app.Params.FlashRstar = 1;
            end
            if ~isfield(app.Params, 'Tdelay') % Flash trigger delay (in s)
                app.Params.Tdelay = 0.6;
            end
            
            t = table(fieldnames(app.Params), struct2cell(app.Params));
            app.tblParams.Data = t;

            app.PlotButton.Enable = "on";
            close(pb);            
        end

        % Menu selected function: Menu3Dplot
        function Menu3DplotSelected(app, event)
            app.Menu3Dplot.Checked = ~app.Menu3Dplot.Checked;
        end

        % Button pushed function: btnInfo
        function btnInfoButtonPushed(app, event)
            Msg = {'\bf Cone-rod gap junction model parameters:';
                   '\rm ';
                   '\bf Tmax \rm End time of the simulation in seconds';
                   '\bf Tdelta \rm Sampling time in seconds';                   
                   '\bf Tdelay \rm Delay time of light flash trigger in seconds';                   
                   '\bf CCjc \rm Cone-Cone coupling conductance in S';
                   '\bf RRjc \rm Rod-Rod coupling conductance in S';
                   '\bf RCjc \rm Rod-Cone coupling conductance in S';
                   '\bf RCjcRandom \rm Enabled use random conductances for RCjc';
                   '\bf RCjcRndMean \rm Mean of the normal distribution to sample random RCjc';
                   '\bf RCjcRndSD \rm S.D. of the normal distribution to sample random RCjc';
                   '\bf Rblocksjc \rm Coupling conductance between blocks of rods in S ';
                   '\bf NoiseRodAmp \rm Noise current amplitude for cones in A';
                   '\bf NoiseRodAmp \rm Noise current amplitude for rods in A';
                   '\bf Rmc \rm Cone membrane resistance in Ohm';
                   '\bf Rmr \rm Rod membrane resistance in Ohm';
                   '\bf Cmc \rm Cone membrane capacitance in F';
                   '\bf Cmr \rm Rod membrane capacitance in F';
                   '\bf Er \rm Rod resting membrane potential in V';
                   '\bf Ec \rm Cone resting membrane potential in V';
                   '\bf SPR \rm Single photon response of rods in A';
                   '\bf t1 \rm First time contstant (rise phase) of rod SPR in s';
                   '\bf t2 \rm Second time contstant (decay phase) of rod SPR in s';
                   '\bf RefCone \rm Reference cone number';
                   '\bf ConeRows \rm Number of rows of cones';
                   '\bf ConeCols \rm Number of columns of cones';
                   '\bf RodRows \rm Number of rows of rods (per cone)';
                   '\bf RodCols \rm Number of columns of rods (per cone)';
                   '\bf RodsIllNum \rm Number of illuminated rods (per cone)';
                   '\bf RodsIllRandom \rm Randomize the position of illuminated rods'
                   '\bf LRmodelFull \rm Full Light Reponse model vs Singple Photon Response'
                   '\rm ';
                   '\rm Licensed under GNU General Public License v3'
                   '\rm Copyright (C) 2024-2025 Luca Della Santina'
                   };

            Opts.Interpreter = 'tex';
            Opts.WindowStyle = 'non-modal';
            Opts.icon = 'help';

            msgbox(Msg, "Model parameters", Opts);
        end

        % Button pushed function: BatchUpdate
        function BatchUpdateButtonPushed(app, event)
            % Update selected batch item with current settings
            L = app.tblParams.Data; % List of user-selected Settings
            app.BatchList.ItemsData(app.BatchList.ValueIndex) = {L};
        end

        % Value changed function: BatchList
        function BatchListValueChanged(app, event)
            value = app.BatchList.Value;
            if ~isempty(value)
                app.BatchUpdate.Enable = "on";
            end
        end

        % Button pushed function: Test
        function TestButtonPushed(app, event)
            % Solve model with current settings
            pb = uiprogressdlg(app.ConeRodModelUIFigure,'Title','Solving Model', 'Indeterminate','on');

            app.Solution = app.testSoluton(pb);
            app.PlotButton.Enable = "on";
            close(pb);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ConeRodModelUIFigure = uifigure('Visible', 'off');
            app.ConeRodModelUIFigure.Name = 'Cone-Rod Model';
            app.GridLayout = uigridlayout(app.ConeRodModelUIFigure);
            app.PnlPlots = uipanel(app.GridLayout);
            app.Panel = uipanel(app.GridLayout);
            app.SolveButton = uibutton(app.Panel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Icon = fullfile(pathToMLAPP, 'icons', '48px-solve.png');
            app.SolveButton.Tooltip = {'Solve differential equations of rod-cone connectivity'};
            app.SolveButton.Text = 'Solve';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = fullfile(pathToMLAPP, 'icons', '48px-graph.png');
            app.PlotButton.Enable = 'off';
            app.PlotButton.Tooltip = {'Plot results of the model for cones and rods'};
            app.PlotButton.Text = 'Plot';
            app.tblParams = uitable(app.Panel);
            app.tblParams.ColumnName = {'Setting'; 'Value'};
            app.tblParams.RowName = {};
            app.tblParams.ColumnSortable = [true false];
            app.tblParams.ColumnEditable = [false true];
            app.BatchAdd = uibutton(app.Panel, 'push');
            app.BatchAdd.ButtonPushedFcn = createCallbackFcn(app, @BatchAddButtonPushed, true);
            app.BatchAdd.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-add.png');
            app.BatchAdd.Tooltip = {'Add current settings to batch list'};
            app.BatchAdd.Text = '';
            app.BatchRemove = uibutton(app.Panel, 'push');
            app.BatchRemove.ButtonPushedFcn = createCallbackFcn(app, @BatchRemoveButtonPushed, true);
            app.BatchRemove.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-remove.png');
            app.BatchRemove.Enable = 'off';
            app.BatchRemove.Tooltip = {'Remove selected batch item from list'};
            app.BatchRemove.Text = '';
            app.BatchClear = uibutton(app.Panel, 'push');
            app.BatchClear.ButtonPushedFcn = createCallbackFcn(app, @BatchClearButtonPushed, true);
            app.BatchClear.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-clear.png');
            app.BatchClear.Enable = 'off';
            app.BatchClear.Tooltip = {'Remove all batch items from list'};
            app.BatchClear.Text = '';
            app.BatchAddRange = uibutton(app.Panel, 'push');
            app.BatchAddRange.ButtonPushedFcn = createCallbackFcn(app, @BatchAddRangeButtonPushed, true);
            app.BatchAddRange.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-add-range.png');
            app.BatchAddRange.Tooltip = {'Add the specified setting''s range to batch list'};
            app.BatchAddRange.Text = '';
            app.BatchList = uilistbox(app.Panel);
            app.BatchList.Items = {};
            app.BatchList.ValueChangedFcn = createCallbackFcn(app, @BatchListValueChanged, true);
            app.BatchList.Tooltip = {'List of batch processing jobs'};
            app.BatchList.DoubleClickedFcn = createCallbackFcn(app, @BatchListDoubleClicked, true);
            app.BatchList.Value = {};
            app.BatchRun = uibutton(app.Panel, 'push');
            app.BatchRun.ButtonPushedFcn = createCallbackFcn(app, @BatchRunButtonPushed, true);
            app.BatchRun.Icon = fullfile(pathToMLAPP, 'icons', '48px-robot.png');
            app.BatchRun.Enable = 'off';
            app.BatchRun.Tooltip = {'Run batch jobs from list'};
            app.BatchRun.Text = 'Process batch';
            app.BatchSetting = uidropdown(app.Panel);
            app.BatchSetting.Items = {};
            app.BatchSetting.Value = {};
            app.FromEditFieldLabel = uilabel(app.Panel);
            app.FromEditFieldLabel.Text = 'From';
            app.BatchFrom = uieditfield(app.Panel, 'numeric');
            app.ToEditFieldLabel = uilabel(app.Panel);
            app.ToEditFieldLabel.Text = 'To';
            app.BatchTo = uieditfield(app.Panel, 'numeric');
            app.StepsEditFieldLabel = uilabel(app.Panel);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.BatchSteps = uieditfield(app.Panel, 'numeric');
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = fullfile(pathToMLAPP, 'icons', '48px-open.png');
            app.LoadButton.Tooltip = {'Load model settings from disk'};
            app.LoadButton.Text = 'Load';
            app.btnInfo = uibutton(app.Panel, 'push');
            app.btnInfo.ButtonPushedFcn = createCallbackFcn(app, @btnInfoButtonPushed, true);
            app.btnInfo.Icon = fullfile(pathToMLAPP, 'icons', '48px-info.png');
            app.btnInfo.Tooltip = {'Information on the model''s parameters'};
            app.btnInfo.Text = '';
            app.BatchUpdate = uibutton(app.Panel, 'push');
            app.BatchUpdate.ButtonPushedFcn = createCallbackFcn(app, @BatchUpdateButtonPushed, true);
            app.BatchUpdate.Icon = fullfile(pathToMLAPP, 'icons', '48px-List-refresh.png');
            app.BatchUpdate.Enable = 'off';
            app.BatchUpdate.Tooltip = {'Update selected batch item with current settings '};
            app.BatchUpdate.Text = '';
            app.Test = uibutton(app.Panel, 'push');
            app.Test.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.Test.Text = {'Test'; 'Solver'};
            app.ContextMenuPlots = uicontextmenu(app.ConeRodModelUIFigure);
            app.Menu3Dplot = uimenu(app.ContextMenuPlots);
            app.Menu3Dplot.MenuSelectedFcn = createCallbackFcn(app, @Menu3DplotSelected, true);
            app.Menu3Dplot.Checked = 'on';
            app.Menu3Dplot.Text = '3D Plot';
            % Assign app.ContextMenuPlots
            app.PnlPlots.ContextMenu = app.ContextMenuPlots;
            % Show the figure after all components are created
            app.ConeRodModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConeRodModel

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConeRodModelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ConeRodModelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConeRodModelUIFigure)
        end
    end
end